classdef App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SelectordeZonasPanel   matlab.ui.container.Panel
        Torre1DropDownLabel    matlab.ui.control.Label
        Torre1DropDown         matlab.ui.control.DropDown
        Torre2DropDownLabel    matlab.ui.control.Label
        Torre2DropDown         matlab.ui.control.DropDown
        Torre3DropDownLabel    matlab.ui.control.Label
        Torre3DropDown         matlab.ui.control.DropDown
        Torre4DropDownLabel    matlab.ui.control.Label
        Torre4DropDown         matlab.ui.control.DropDown
        MessageEditFieldLabel  matlab.ui.control.Label
        MessageEditField       matlab.ui.control.EditField
        SendButton             matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Port Creation
            instrreset
            global puerto;
            %Port Configuration
            puerto=serial('COM8','BaudRate',9600);
            puerto.InputBufferSize = 10000000;
            %Port Open
            fopen(puerto)
           % fopen(puerto);
            
            global message;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            instrreset
        end

        % Value changed function: MessageEditField
        function MessageEditFieldValueChanged(app, event)
            global message;
            value = app.MessageEditField.Value;
            message = value
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            global puerto
            global message
            
            fwrite(puerto,message)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            setAutoResize(app, app.UIFigure, true)
            app.SelectordeZonasPanel = uipanel(app.UIFigure);
            app.SelectordeZonasPanel.Title = 'Selector de Zonas';
            app.Torre1DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre1DropDownLabel.Text = 'Torre 1';
            app.Torre1DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre1DropDown.Items = {'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.Torre1DropDown.Value = 'Zona 1';
            app.Torre2DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre2DropDownLabel.Text = 'Torre 2';
            app.Torre2DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre2DropDown.Items = {'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.Torre2DropDown.Value = 'Zona 1';
            app.Torre3DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre3DropDownLabel.Text = 'Torre 3';
            app.Torre3DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre3DropDown.Items = {'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.Torre3DropDown.Value = 'Zona 1';
            app.Torre4DropDownLabel = uilabel(app.SelectordeZonasPanel);
            app.Torre4DropDownLabel.Text = 'Torre 4';
            app.Torre4DropDown = uidropdown(app.SelectordeZonasPanel);
            app.Torre4DropDown.Items = {'Zona 1', 'Zona 2', 'Zona 3', 'Zona 4', 'Zona 5', 'Zona 6'};
            app.Torre4DropDown.Value = 'Zona 1';
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.MessageEditField.ValueChangedFcn = createCallbackFcn(app, @MessageEditFieldValueChanged, true);
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end